classdef ScolioBolio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        Tree                            matlab.ui.container.CheckBoxTree
        DeformationRibcageNode          matlab.ui.container.TreeNode
        RibcageNode                     matlab.ui.container.TreeNode
        SplittedRibcageNode             matlab.ui.container.TreeNode
        SpineNode                       matlab.ui.container.TreeNode
        SpinecenterlineNode             matlab.ui.container.TreeNode
        RibsNode                        matlab.ui.container.TreeNode
        SegmentedribsNode               matlab.ui.container.TreeNode
        RibssegmentedNode               matlab.ui.container.TreeNode
        RibssegmentedcenterlinesNode    matlab.ui.container.TreeNode
        FoundribpairsNode               matlab.ui.container.TreeNode
        RibpairsNode                    matlab.ui.container.TreeNode
        RibpairscenterlinesNode         matlab.ui.container.TreeNode
        UIAxes                          matlab.ui.control.UIAxes
        PreOperativeLabel               matlab.ui.control.Label
        StartAlgorithmButton            matlab.ui.control.Button
        GridLayout7                     matlab.ui.container.GridLayout
        SaveImageButton                 matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout9_2                   matlab.ui.container.GridLayout
        Tree_2                          matlab.ui.container.CheckBoxTree
        DeformationRibcageNode_2        matlab.ui.container.TreeNode
        RibcageNode_2                   matlab.ui.container.TreeNode
        SplittedRibcageNode_2           matlab.ui.container.TreeNode
        SpineNode_2                     matlab.ui.container.TreeNode
        SpinecenterlineNode_2           matlab.ui.container.TreeNode
        RibsNode_2                      matlab.ui.container.TreeNode
        SegmentedribsNode_2             matlab.ui.container.TreeNode
        RibssegmentedNode_2             matlab.ui.container.TreeNode
        RibssegmentedcenterlinesNode_2  matlab.ui.container.TreeNode
        FoundribpairsNode_2             matlab.ui.container.TreeNode
        RibpairsNode_2                  matlab.ui.container.TreeNode
        RibpairscenterlinesNode_2       matlab.ui.container.TreeNode
        GridLayout8                     matlab.ui.container.GridLayout
        SaveImageButton_2               matlab.ui.control.Button
        LoadImageButton_2               matlab.ui.control.Button
        StartAlgorithmButton_2          matlab.ui.control.Button
        PostOperativeLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        colorribcage=[255,255,255]
        colorspine=[0,255,0]
        colorribs=[0,0,255]
        
        fullpathinput=0
        fullpathoutput=0
        
        pcribcage=0       
        ribcage=0
        ribcageplot=0
        
        pcspinecenterline=0
        spinecenterlineplot=0

        pcspine=0
        spineplot=0

        pcribs=0
        ribs=0
        ribsplot=0

        pcindividual_ribs=0
        individual_ribsplot=0

        pcrib_pairs=0
        rib_pairsplot=0

        pcdeformation_ribcage=0
        deformation_ribcageplot=0

        done=0
        
        
        %%

    end
    
    methods (Access = private)      
        function myUpdateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {'1x', currentFigureWidth/2};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file, path] = uigetfile('*.nii', 'Select a file');
            delete(f);
            if ~isequal(file, 0)
                d = uiprogressdlg(app.UIFigure,'Title','Loading image...', 'Indeterminate','on');
                drawnow
                app.fullpathinput = string(fullfile(path, file));
                app.LoadImageButton.Text = file;

                axes = app.UIAxes;
                delete(axes.Children);
                app.pcspinecenterline=0;
                app.spinecenterlineplot=0;
                app.pcspine=0;
                app.spineplot=0;        
                app.pcribs=0;
                app.ribs=0;
                app.ribsplot=0;        
                app.pcindividual_ribs=0;
                app.individual_ribsplot=0;        
                app.pcrib_pairs=0;
                app.rib_pairsplot=0;        
                app.pcdeformation_ribcage=0;
                app.deformation_ribcageplot=0;
                
                [app.pcribcage, app.ribcage] = get_ribcage(app.fullpathinput, 5, 3, 1300, app.colorribcage);
                
                pcshow(app.pcribcage, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.ribcageplot = axes.Children(end);
                app.Tree.CheckedNodes = [app.RibcageNode];
                
                close(d);
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            if app.done == 1
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file, path] = uiputfile('*.*', 'Select a folder');
                delete(f);
                app.fullpathoutput = string(fullfile(path, file));
                if isstring(path)
                    app.SaveImageButton.Text = path;
                        d = uiprogressdlg(app.UIFigure,'Title','Saving images...', 'Indeterminate','on');
                        drawnow
                        pcwrite(app.pcribage, path + 'ribcage.ply');
                        pcwrite(app.pcribs, path+ 'ribs.ply');
                        pcwrite(app.pcspine, path+ 'spine.ply');
                        for i = 1:length(app.pcindividual_ribs)
                            pcwrite(app.pcindividual_ribs{i}, path+'rib_'+string(i)+'.ply');
                        end
                        for i = 1:length(app.pcrib_pairs)
                            pcwrite(app.pcrib_pairs{i,1}, path+'rib_'+string(i)+'_1.ply');
                            pcwrite(app.pcrib_pairs{i,2}, path+'rib_'+string(i)+'_2.ply');
                        end
                        close(d);
                else
                    uialert(app.UIFigure, 'Insert valid folder', 'Warning')
                end
            else
                uialert(app.UIFigure, 'Not done processing', 'Warning'); 
            end
        end

        % Button pushed function: StartAlgorithmButton
        function StartAlgorithmButtonPushed(app, event)
            if isstring(app.fullpathinput)
                d = uiprogressdlg(app.UIFigure,'Title','Seperating ribcage...', 'Indeterminate','on');
                drawnow
                [app.pcspinecenterline, app.pcspine, app.pcribs, app.ribs]=seperate_ribcage(app.ribcage, app.colorspine, app.colorribs);
                axes=app.UIAxes;
                delete(axes.Children);
                app.ribcageplot = 0;
                app.spineplot = 0;
                app.ribsplot = 0;
                app.spinecenterlineplot = 0;
                app.individual_ribsplot = 0;
                app.rib_pairsplot = 0;
                app.deformation_ribcageplot = 0;
                hold(axes);
                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]); 
                app.spineplot = axes.Children(end);
                pcshow(app.pcribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.ribsplot = axes.Children(end);
                pcshow(app.pcspinecenterline, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.spinecenterlineplot = axes.Children(end);
                hold(axes);   
                app.Tree.CheckedNodes = [app.SpineNode, app.RibsNode, app.SpinecenterlineNode];
                close(d);

                d = uiprogressdlg(app.UIFigure,'Title','Seperating ribs...', 'Indeterminate','on');
                drawnow
                [app.pcindividual_ribs]=seperate_ribs(app.ribs, app.pcspinecenterline);   
                delete(axes.Children);
                app.ribcageplot = 0;
                app.spineplot = 0;
                app.ribsplot = 0;
                app.spinecenterlineplot = 0;
                app.individual_ribsplot = 0;
                app.rib_pairsplot = 0;
                app.deformation_ribcageplot = 0;
                hold(axes);
                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.spineplot = axes.Children(end);
                app.individual_ribsplot = [];
                for i =1:length(app.pcindividual_ribs)
                    pcshow(app.pcindividual_ribs{i}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                    app.individual_ribsplot = [app.individual_ribsplot axes.Children(end)];
                end
                hold(axes)
                app.Tree.CheckedNodes = [app.SpineNode, app.RibssegmentedcenterlinesNode, app.SpinecenterlineNode];
                close(d);
                               
                d = uiprogressdlg(app.UIFigure,'Title','Finding rib pairs...', 'Indeterminate','on');
                drawnow
                [app.pcrib_pairs] = find_rib_pairs(app.pcindividual_ribs, app.individual_ribs, app.pcspinecenterline);  
                delete(axes.Children);
                app.ribcageplot = 0;
                app.spineplot = 0;
                app.ribsplot = 0;
                app.spinecenterlineplot = 0;
                app.individual_ribsplot = 0;
                app.rib_pairsplot = 0;
                app.deformation_ribcageplot = 0;
                hold(axes);
                app.rib_pairsplot = [];
                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.spineplot = axes.Children(end);
                for i =1:length(app.pcrib_pairs)
                    pcshow(app.pcrib_pairs{i,1}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                    app.rib_pairsplot = [app.rib_pairsplot axes.Children(end)];
                    pcshow(app.pcrib_pairs{i,2}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                    app.rib_pairsplot = [app.rib_pairsplot axes.Children(end)];
                end
                hold(axes)
                app.Tree.CheckedNodes = [app.SpineNode, app.RibpairscenterlinesNode, app.SpinecenterlineNode];
                close(d);

%                 d = uiprogressdlg(app.UIFigure,'Title','Calculating deformity of ribs...', 'Indeterminate','on');
%                 drawnow
%                 app.pcdeformation_ribcage = color_deformity(app.pcrib_pairs, app.ribcage);
%                 delete(app.rib_pairsplot);
%                 app.rib_pairsplot = 0;
%                 delete(app.spineplot);
%                 app.spineplot = 0;
%                 delete(app.spinecenterlineplot);
%                 app.spinecenterlineplot = 0;
%                 hold(axes);
%                 pcshow(app.pcdeformation_ribcage, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
%                 app.deformation_ribcageplot = axes.Children(end);
%                 hold(axes)
%                 app.Tree.CheckedNodes = [app.DeformationRibcageNode];
%                 close(d);
                
                app.done = 1;
            else
                uialert(app.UIFigure, 'First load an image!','Warning');
            end

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            node = app.Tree.SelectedNodes;
            prevnodes = event.PreviousCheckedNodes;
            nodes = [];
            nodes_turned_on = [];
            axes = app.UIAxes;

            if isempty(node.Children)
                nodes = [nodes node];
            else
                for i=1:length(node.Children)
                    nodes = [nodes node.Children(i)];
                end
            end

            for i=1:length(nodes)
                nodes_turned_on = [nodes_turned_on 0];
                if ~isempty(prevnodes)
                    turned_on = true;
                    for j=1:length(prevnodes)
                        if strcmp(prevnodes(j).Text, nodes(i).Text)
                            turned_on = false;
                            break
                        end
                    end
                    nodes_turned_on(i) = turned_on;
                else
                    if nodes_turned_on(i) == 0
                        nodes_turned_on(i) = true;
                    end
                end
            end
            
            for i=1:length(nodes)
                switch nodes(i).Text
                    case "Ribcage"
                        if nodes_turned_on(i)
                            if app.pcribcage ~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow

                                hold(axes)
                                pcshow(app.pcribcage, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.ribcageplot = axes.Children(end);
                                hold(axes);    
                                
                                close(d)
                            else
                                uialert(app.UIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if app.ribcageplot~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
                                drawnow

                                delete(app.ribcageplot)
                                app.ribcageplot = 0;

                                close(d)
                            end
                        end

                    case "Spine"
                        if nodes_turned_on(i)
                            if app.pcspine ~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow

                                hold(axes);
                                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.spineplot = axes.Children(end); 
                                hold(axes);
                                
                                close(d);
                            else
                                uialert(app.UIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if app.spineplot~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
                                drawnow

                                delete(app.spineplot)
                                app.spineplot = 0;

                                close(d)
                            end
                        end

                    case "Spine centerline"
                        if nodes_turned_on(i)
                            if app.pcspinecenterline ~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcspinecenterline, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.spinecenterlineplot = axes.Children(end); 
                                hold(axes);

                                close(d);
                            else
                                uialert(app.UIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if app.spinecenterlineplot~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
                                drawnow
                                
                                delete(app.spinecenterlineplot)
                                app.spinecenterlineplot = 0;

                                close(d);
                            end
                        end

                    case "Ribs"
                        if nodes_turned_on(i)
                            if app.pcribs ~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.ribsplot = axes.Children(end);
                                hold(axes);

                                close(d)
                            else
                                uialert(app.UIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if app.ribsplot~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
                                drawnow
                                
                                delete(app.ribsplot)
                                app.ribsplot = 0;

                                close(d);
                            end
                        end
                     
%                     case "Ribs segmented"
%                         if nodes_turned_on(i)
%                             if app.pcindividual_ribs ~=0
%                                 d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
%                                 drawnow
%                                 
%                                 hold(axes);
%                                 app.individual_ribsplot = [];
%                                 for j=1:length(app.pcindividual_ribs)
%                                     pcshow(app.pcindividual_ribs{j}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
%                                     app.individual_ribsplot = [app.individual_ribsplot axes.Children(end)];
%                                 end
%                                 hold(axes)
% 
%                                 close(d)
%                             else
%                                 uialert(app.UIFigure, 'Data not available!', 'Warning');
%                             end
%                         else
%                             if app.ribsplot~=0
%                                 d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
%                                 drawnow
%                                 
%                                 delete(app.individual_ribsplot)
%                                 app.individual_ribsplot = 0;
% 
%                                 close(d);
%                             end
%                         end
                    
                    case "Ribs segmented centerlines"
                        if nodes_turned_on(i)
                            if app.pcindividual_ribs ~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                app.individual_ribsplot = [];
                                for j=1:length(app.pcindividual_ribs)
                                    pcshow(app.pcindividual_ribs{j}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                    app.individual_ribsplot = [app.individual_ribsplot axes.Children(end)];
                                end
                                hold(axes)

                                close(d)
                            else
                                uialert(app.UIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if app.ribsplot~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
                                drawnow
                                
                                delete(app.individual_ribsplot)
                                app.individual_ribsplot = 0;

                                close(d);
                            end
                        end

%                     case "Ribs pairs"
%                         if nodes_turned_on(i)
%                             if app.pcindividual_ribs ~=0
%                                 d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
%                                 drawnow
%                                 
%                                 hold(axes);
%                                 app.individual_ribsplot = [];
%                                 for j =1:length(app.pcrib_pairs)
%                                     pcshow(app.pcrib_pairs{j,1}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
%                                     app.rib_pairsplot = [app.rib_pairsplot axes.Children(end)];
%                                     pcshow(app.pcrib_pairs{j,2}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
%                                     app.rib_pairsplot = [app.rib_pairsplot axes.Children(end)];
%                                 end
%                                 hold(axes)
% 
%                                 close(d)
%                             else
%                                 uialert(app.UIFigure, 'Data not available!', 'Warning');
%                             end
%                         else
%                             if app.ribsplot~=0
%                                 d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
%                                 drawnow
%                                 
%                                 delete(app.individual_ribsplot)
%                                 app.individual_ribsplot = 0;
% 
%                                 close(d);
%                             end
%                         end
                    
                    case "Ribs pairs centerlines"
                        if nodes_turned_on(i)
                            if app.pcrib_pairs ~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                app.rib_pairsplot = [];
                                for j =1:length(app.pcrib_pairs)
                                    pcshow(app.pcrib_pairs{j,1}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                    app.rib_pairsplot = [app.rib_pairsplot axes.Children(end)];
                                    pcshow(app.pcrib_pairs{j,2}, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                    app.rib_pairsplot = [app.rib_pairsplot axes.Children(end)];
                                end
                                hold(axes)

                                close(d)
                            else
                                uialert(app.UIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if app.ribsplot~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
                                drawnow
                                
                                delete(app.rib_pairsplot)
                                app.rib_pairsplot = 0;

                                close(d);
                            end
                        end

                    case "Deformation Ribcage"
                        if nodes_turned_on(i)
                            if app.pcdeformation_ribcage ~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcdeformation_ribcage, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.deformation_ribcageplot = axes.Children(end);
                                hold(axes)

                                close(d)
                            else
                                uialert(app.UIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if app.ribsplot~=0
                                d = uiprogressdlg(app.UIFigure,'Title','Removing image...', 'Indeterminate','on');
                                drawnow
                                
                                delete(app.deformation_ribcageplot)
                                app.deformation_ribcageplot = 0;

                                close(d);
                            end
                        end
                     

                end
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {838, 838};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {770, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.LoadImageButton = uibutton(app.GridLayout7, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.GridLayout7, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.StartAlgorithmButton = uibutton(app.GridLayout5, 'push');
            app.StartAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @StartAlgorithmButtonPushed, true);
            app.StartAlgorithmButton.Text = 'Start Algorithm';
            app.PreOperativeLabel = uilabel(app.GridLayout5);
            app.PreOperativeLabel.Text = 'Pre-Operative';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.UIAxes = uiaxes(app.GridLayout9);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tree = uitree(app.GridLayout9, 'checkbox');
            app.DeformationRibcageNode = uitreenode(app.Tree);
            app.DeformationRibcageNode.Text = 'Deformation Ribcage';
            app.RibcageNode = uitreenode(app.Tree);
            app.RibcageNode.Text = 'Ribcage';
            app.SplittedRibcageNode = uitreenode(app.Tree);
            app.SplittedRibcageNode.Text = 'Splitted Ribcage';
            app.SpineNode = uitreenode(app.SplittedRibcageNode);
            app.SpineNode.Text = 'Spine';
            app.SpinecenterlineNode = uitreenode(app.SplittedRibcageNode);
            app.SpinecenterlineNode.Text = 'Spine centerline';
            app.RibsNode = uitreenode(app.SplittedRibcageNode);
            app.RibsNode.Text = 'Ribs';
            app.SegmentedribsNode = uitreenode(app.Tree);
            app.SegmentedribsNode.Text = 'Segmented ribs';
            app.RibssegmentedNode = uitreenode(app.SegmentedribsNode);
            app.RibssegmentedNode.Text = 'Ribs segmented';
            app.RibssegmentedcenterlinesNode = uitreenode(app.SegmentedribsNode);
            app.RibssegmentedcenterlinesNode.Text = 'Ribs segmented centerlines';
            app.FoundribpairsNode = uitreenode(app.Tree);
            app.FoundribpairsNode.Text = 'Found rib pairs';
            app.RibpairsNode = uitreenode(app.FoundribpairsNode);
            app.RibpairsNode.Text = 'Rib pairs';
            app.RibpairscenterlinesNode = uitreenode(app.FoundribpairsNode);
            app.RibpairscenterlinesNode.Text = 'Rib pairs centerlines';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.PostOperativeLabel = uilabel(app.GridLayout3);
            app.PostOperativeLabel.Text = 'Post-Operative';
            app.StartAlgorithmButton_2 = uibutton(app.GridLayout3, 'push');
            app.StartAlgorithmButton_2.Text = 'Start Algorithm';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.LoadImageButton_2 = uibutton(app.GridLayout8, 'push');
            app.LoadImageButton_2.Text = 'Load Image';
            app.SaveImageButton_2 = uibutton(app.GridLayout8, 'push');
            app.SaveImageButton_2.Text = 'Save Image';
            app.GridLayout9_2 = uigridlayout(app.GridLayout3);
            app.Tree_2 = uitree(app.GridLayout9_2, 'checkbox');
            app.DeformationRibcageNode_2 = uitreenode(app.Tree_2);
            app.DeformationRibcageNode_2.Text = 'Deformation Ribcage';
            app.RibcageNode_2 = uitreenode(app.Tree_2);
            app.RibcageNode_2.Text = 'Ribcage';
            app.SplittedRibcageNode_2 = uitreenode(app.Tree_2);
            app.SplittedRibcageNode_2.Text = 'Splitted Ribcage';
            app.SpineNode_2 = uitreenode(app.SplittedRibcageNode_2);
            app.SpineNode_2.Text = 'Spine';
            app.SpinecenterlineNode_2 = uitreenode(app.SplittedRibcageNode_2);
            app.SpinecenterlineNode_2.Text = 'Spine centerline';
            app.RibsNode_2 = uitreenode(app.SplittedRibcageNode_2);
            app.RibsNode_2.Text = 'Ribs';
            app.SegmentedribsNode_2 = uitreenode(app.Tree_2);
            app.SegmentedribsNode_2.Text = 'Segmented ribs';
            app.RibssegmentedNode_2 = uitreenode(app.SegmentedribsNode_2);
            app.RibssegmentedNode_2.Text = 'Ribs segmented';
            app.RibssegmentedcenterlinesNode_2 = uitreenode(app.SegmentedribsNode_2);
            app.RibssegmentedcenterlinesNode_2.Text = 'Ribs segmented centerlines';
            app.FoundribpairsNode_2 = uitreenode(app.Tree_2);
            app.FoundribpairsNode_2.Text = 'Found rib pairs';
            app.RibpairsNode_2 = uitreenode(app.FoundribpairsNode_2);
            app.RibpairsNode_2.Text = 'Rib pairs';
            app.RibpairscenterlinesNode_2 = uitreenode(app.FoundribpairsNode_2);
            app.RibpairscenterlinesNode_2.Text = 'Rib pairs centerlines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScolioBolio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end